classdef Application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        UIAxes                 matlab.ui.control.UIAxes
        UIAxes_2               matlab.ui.control.UIAxes
        UIAxes_3               matlab.ui.control.UIAxes
        UIAxes_4               matlab.ui.control.UIAxes
        UIAxes_5               matlab.ui.control.UIAxes
        UIAxes_6               matlab.ui.control.UIAxes
        UIAxes_7               matlab.ui.control.UIAxes
        UIAxes_8               matlab.ui.control.UIAxes
        UIAxes_9               matlab.ui.control.UIAxes
        UIAxes_10              matlab.ui.control.UIAxes
        UploadAnImageButton_1  matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: UploadAnImageButton_1
        function UploadAnImageButton_Pushed(app, event)
            %             final = 0;
            %             while (final == 0)
            %                 [File_Name, Path_Name] = uigetfile('PATHNAME');
            %                 img = imread([Path_Name,File_Name]);
            %                 [final, e_trans, r_trans] = ageDetect(img);
            %                 fprintf ("%d adiosssdefrr", final)
            %                 if (final ~= 0)
            %                    break;
            %                 end
            %                 imshow(img, 'Parent', app.UIAxes)
            %                 f = uifigure;
            %                 uialert(f,'Unable to finish the algorithmn. Please upload another image','alert');
            %             end
            
            [File_Name, Path_Name] = uigetfile('PATHNAME');
            img = imread([Path_Name,File_Name]);
            [final, e_trans, r_trans] = ageDetect(img);
          
            imshow(img, 'Parent', app.UIAxes)
            imshow(r_trans{1, 1}, 'Parent', app.UIAxes_2)
            imshow(r_trans{1, 2}, 'Parent', app.UIAxes_3)
            imshow(r_trans{1, 3}, 'Parent', app.UIAxes_4)
            imshow(r_trans{1, 4}, 'Parent', app.UIAxes_5)
            imshow(e_trans{1, 1}, 'Parent', app.UIAxes_6)
            imshow(e_trans{1, 3}, 'Parent', app.UIAxes_7)
            imshow(e_trans{1, 4}, 'Parent', app.UIAxes_8)
            imshow(e_trans{1, 5}, 'Parent', app.UIAxes_9)
            imshow(final, 'Parent', app.UIAxes_10)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Image')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Ratio 1 (process 1)')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Ratio 2 (process 2)')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Ratio 3 (process 3)')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'Ratio 4 (process 4)')
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, 'Gray Image (process 5)')
            app.UIAxes_7 = uiaxes(app.UIFigure);
            title(app.UIAxes_7, 'Binary Image (process 6)')
            app.UIAxes_8 = uiaxes(app.UIFigure);
            title(app.UIAxes_8, 'Filtered Image (process 7)')
            app.UIAxes_9 = uiaxes(app.UIFigure);
            title(app.UIAxes_9, 'Edge Image (process 8)')
            app.UIAxes_10 = uiaxes(app.UIFigure);
            title(app.UIAxes_10, 'Result')
            app.UploadAnImageButton_1 = uibutton(app.UIFigure, 'push');
            app.UploadAnImageButton_1.ButtonPushedFcn = createCallbackFcn(app, @UploadAnImageButton_Pushed, true);
            app.UploadAnImageButton_1.Text = 'Upload An Image';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Application

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end